classdef cdr_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        CoefficientEditField         matlab.ui.control.EditField
        CoefficientLabel             matlab.ui.control.Label
        FinalPositionCameraAngleEditField  matlab.ui.control.EditField
        FinalPositionCameraAngleEditFieldLabel  matlab.ui.control.Label
        ReplayButton                 matlab.ui.control.Button
        InorOutEditField             matlab.ui.control.EditField
        InorOutLabel                 matlab.ui.control.Label
        OuputParametersLabel         matlab.ui.control.Label
        InputParametersLabel         matlab.ui.control.Label
        ShowCoefficientofRestitutionCheckBox  matlab.ui.control.CheckBox
        VolleyorServeButtonGroup     matlab.ui.container.ButtonGroup
        ServeButton                  matlab.ui.control.RadioButton
        VolleyButton                 matlab.ui.control.RadioButton
        SinglesorDoublesButtonGroup  matlab.ui.container.ButtonGroup
        SinglesButton                matlab.ui.control.RadioButton
        DoublesButton                matlab.ui.control.RadioButton
        RunButton                    matlab.ui.control.Button
        SelectShotDropDown           matlab.ui.control.DropDown
        SelectShotLabel              matlab.ui.control.Label
    end

    properties (Access = public)
        % DELETE LATER - KEEP AND UPDATE WHILE GUI IS W.I.P
        % 4/26 J - Entirety of MatlabClient_5.m was copied into the RunButtonPushed
        % callback function. matlabclient5 calls MoveTennisBall.m and
        % MoveCamera.m files, which are currently nowhere in the .mlapp.
        % Either call files from app or copy functions into app? question
        % mark
        %
        % 4/27 Jacky - There are some function blocks still in the
        % RunButtonPushed callback function that are now obselete as they 
        % are included in other function calls, namely
        % for selecting path, MODE, MATCH_TYPE, and SHOT_TYPE
        % Also, every line included in generating plots keep showing
        % warnings, too zoinked out to figure out what they mean rn. The
        % plot generation blocks might need to be moved to another callback
        % function, maybe ReplayButtonPushed?
        
        % Image Transfer Dimensions
        width;          % total # of px in the x direction of the sensor
        height;         % total # of px in the y direction of the sensor

        path;           % path to .dat file in datFiles folder; contains ball info
        
        % calcaulated coordinates derived from unity simulation
        calc_X;         % X pos of ball according to dan's coordinate system
        calc_Y;         % Y pos of ball according to dan's coordiante system
        calc_Z;         % Z pos of ball according to dan's coordinate system
    
        % real coordinates from the .dat files
        real_X;         % X pos of ball 
        real_Y;         % Y pos of ball
        real_Z;         % Z pos of ball

        MODE;           % 1 -> coefficient of restitution, 2 -> ball is inside or out
        MATCH_TYPE;     % 1 -> singles mode, -> 2 doubles mode
        SHOT_TYPE;      % 1 -> serve mode, 2 -> volley mode


        t;              % time 
    end
 

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.width = 752;
            app.height = 480;
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            % Initiates shot simulation
            
            % MatlabClient_4 Connest to a Zynq server and continuously awaits a request
            % from server for a frame at time t
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % CLIENT INITIALIZATION
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%


            % Function to preprocess images (convert to grayscale)
            preprocessImage = @(img) rgb2gray(img);
            
            % Get and save Empty Court images (Left/Right). 
            emptyLeftImage = imread("../../testImages/LeftEmptyCourt.jpg");
            emptyRightImage = imread("../../testImages/rightEmptyCourt.jpg");
            emptyLeftGray = preprocessImage(emptyLeftImage);
            emptyRightGray = preprocessImage(emptyRightImage);
            
            
            %Connect to sever;
            server_ip   = '129.21.43.47';     % IP address of the server -NEEDS CHANGE
            
            % NO CHNAGE
            server_port = 9999;                % Server Port of the sever
            client = tcpclient(server_ip,server_port);
            fprintf(1,"Connected to server\n");
            
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % SEND SERVER INIT PARAMETERS
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %SEND
            write(client,'0'); %Transfer Protocol
            flush(client);
            
            % having coeff or ball status is EXCLUSIVE, only one can be
            % displayed at a time
            write(client, app.MODE);
            flush(client);
            
            write(client, app.MATCH_TYPE);
            flush(client);
            
            write(client, app.SHOT_TYPE);
            flush(client);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % REQUEST-RETRIEVE-RESPOND LOOP
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % Tell Server to begin processing a shot
            write(client,'1'); %Transfer Protocol
            flush(client);
            
            % Loop until Server sends special value
            while 1
            
                % 1. Wait for request from server (t value), exit if special value
                request = read(client, 1, 'uint32');
                request                 % displays t time
                if request == 99999     % too long wait, breaks
                    break
                end
            
                % 2. Retrieve Left/Right Unity images at time t
                errorCode = MoveTennisBall(request, app.path);
            
                if errorCode == 0 
                    [leftImage, rightImage] = MoveCamera(request, 0); 
            
                    % 3. Grayscale Left/Right
                    BallLeftGray  = preprocessImage(leftImage);
                    BallRightGray = preprocessImage(rightImage);
            
                    % 4. Send Left/Right
                    %Package all four images
                    imageStack = uint8(ones(app.height,app.width,8));
            
                    imageStack(:,:,1) = BallLeftGray;
                    imageStack(:,:,2) = emptyLeftGray;
                    imageStack(:,:,3) = BallLeftGray;
                    imageStack(:,:,4) = emptyLeftGray;
                    imageStack(:,:,5) = emptyRightGray;
                    imageStack(:,:,6) = ballRightGray;
                    imageStack(:,:,7) = emptyLeftGray;
                    imageStack(:,:,8) = ballLeftGray;
            
                    imageStack = permute(imageStack,[3 2 1]);
                    write(client,imageStack(:)); %SEND
                    flush(client);
                else
                    % 4. Send Left/Right
                    %Package all four images
                    disp('Printing zeros')
                    imageStack = uint8(ones(app.height,app.width,8));
                    imageStack(:,:,1) = ones(480, 752) * 0;
                    imageStack(:,:,2) = ones(480, 752) * 0;
                    imageStack(:,:,3) = ones(480, 752) * 0;
                    imageStack(:,:,4) = ones(480, 752) * 0;
                    imageStack(:,:,5) = ones(480, 752) * 0;
                    imageStack(:,:,6) = ones(480, 752) * 0;
                    imageStack(:,:,7) = ones(480, 752) * 0;
                    imageStack(:,:,8) = ones(480, 752) * 0;
            
                    imageStack = permute(imageStack,[3 2 1]);
                    write(client,imageStack(:)); %SEND
                    flush(client);
                    break
                end
            
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % RESULTS HANDELING
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            write(client,'2'); %Transfer Protocol
            flush(client);
            
            % Receive calculated X,Y,Z position
            % XYZ positions of the ball relative to the coordinate system
            % that dan made up
            numFrames = read(client, 1, 'uint32');
            app.calc_X = read(client, numFrames, 'double');
            app.calc_Y = read(client, numFrames, 'double');
            app.calc_Z = read(client, numFrames, 'double');
            app.t = read(client, numFrames, 'uint32');
            disp('here')
            
            for i = 1:numFrames
                app.real_X = zeros(size(app.calc_X));
                app.real_Y = zeros(size(app.calc_X));
                app.real_Z = zeros(size(app.calc_X));
                ballData = load(app.path);
            
                % Populate actualDepths using indices from calculatedDepths_t
                for i = 1:length(app.t)
                    t_index = app.t(i);
                    if t_index <= size(ballData, 1)
                        app.real_X(i) = ballData(t_index, 3);
                        app.real_Y(i) = ballData(t_index, 1);
                        app.real_Z(i) = ballData(t_index, 2);
                    else
                        error('Index exceeds the number of rows in ballData file.');
                    end
                end
            end
            
            % Plot 3D coordinates
            figure;
            plot3(app.calc_X, app.calc_Y, app.calc_Z, 'ro'); % Plot calculated positions in red
            hold on;
            plot3(app.real_X, app.real_Y, app.real_Z, 'bo'); % Plot real positions in blue
            legend('Calculated Position', 'Real Position');
            title('3D Plot of Real and Calculated Positions');
            xlabel('X Position');
            ylabel('Y Position');
            zlabel('Z Position');
            grid on;
            
            
            % Plot X coordinate
            figure;
            plot(app.t, app.calc_X, 'r'); % Plot calculated X positions in red
            hold on;
            plot(app.t, app.real_X, 'b'); % Plot real X positions in blue
            legend('Calculated X', 'Real X');
            title('Comparison of Calculated and Real X Positions Over Time');
            xlabel('Time');
            ylabel('X Position');
            grid on;
            
            
            % Plot Y coordinate
            figure;
            plot(app.t, app.calc_Y, 'r'); % Plot calculated Y positions in red
            hold on;
            plot(app.t, app.real_Y, 'b'); % Plot real Y positions in blue
            legend('Calculated Y', 'Real Y');
            title('Comparison of Calculated and Real Y Positions Over Time');
            xlabel('Time');
            ylabel('Y Position');
            grid on;
            
            
            % Plot Z coordinate
            figure;
            plot(app.t, app.calc_Z, 'r'); % Plot calculated Z positions in red
            hold on;
            plot(app.t, app.real_Z, 'b'); % Plot real Z positions in blue
            legend('Calculated Z', 'Real Z');
            title('Comparison of Calculated and Real Z Positions Over Time');
            xlabel('Time');
            ylabel('Z Position');
            grid on;
            
            
            write(client,'9999');
        end

        % Selection changed function: SinglesorDoublesButtonGroup
        function SinglesorDoublesButtonGroupSelectionChanged(app, event)
            % Selector for shot in single or doubles, returns value
            % MATCH_TYPE; 1 -> singles mode, -> 2 doubles mode
            % app.SinglesButton
            % app.DoublesButton
            selectedButton = app.SinglesorDoublesButtonGroup.SelectedObject;

            if selectedButton == app.SinglesButton      % 1 -> singles mode
                app.MATCH_TYPE = 1;                     
            elseif selectedButton == app.DoublesButton  % 2 -> doubles mode
                app.MATCH_TYPE = 2;
            end
        end

        % Selection changed function: VolleyorServeButtonGroup
        function VolleyorServeButtonGroupSelectionChanged(app, event)
            % Selector for serve or volley shot, returns value
            % SHOT_TYPE; 1 -> serve mode, 2 -> volley mode
            % app.ServeButton
            % app.VolleyButton
            selectedButton = app.VolleyorServeButtonGroup.SelectedObject;
            
            if selectedButton == app.ServeButton        % 1 -> serve mode
                app.SHOT_TYPE = 1;
            elseif selectedButton == app.VolleyButton   % 2 -> doubles mode
                app.SHOT_TYPE = 2;
            end
        end

        % Value changed function: SelectShotDropDown
        function SelectShotDropDownValueChanged(app, event)
            % item values = Shot 1, Shot 2, Shot 3, Shot 4, Shot 5
            % item values can be manually added in the dropdown list using
            % the + button, and deleted using the - minus button
            % Case statement? Or grab number from shot # and append to call
            % to file location to grab .dat from datFiles

            selectedItem = app.SelectShotDropDown.Value;
            dataSetNum = selectedItem(5); % take string of item and grabs just the #
            if app.SHOT_TYPE == 1                                           % 1 -> serve mode
                app.path = "../datFiles/serve" + dataSetNum + ".dat";       % concats data set # to serve
            elseif app.SHOT_TYPE == 2                                       % 2 -> doubles mode
                app.path = "../datFiles/volley" + dataSetNum + ".dat";      % concats data set # to volley
            end
                
        end

        % Value changed function: ShowCoefficientofRestitutionCheckBox
        function ShowCoefficientofRestitutionCheckBoxValueChanged(app, event)
            % imo this should be removed, just have coeff show if
            % applicable rather than making it a choice, refer to MODE in
            % main function - Jacky 4/26
            value = app.ShowCoefficientofRestitutionCheckBox.Value;
            
        end

        % Button pushed function: ReplayButton
        function ReplayButtonPushed(app, event)
            % Take calc_X, calc_Y over t time  to perform order 1 polyfit
            % graph polyfit on empty court from birds eye view
            % and mark when the ball hits the ground at y ~= 0
        end

        % Value changed function: InorOutEditField
        function InorOutEditFieldValueChanged(app, event)
            % 

            value = app.InorOutEditField.Value;
  
        end

        % Value changed function: CoefficientEditField
        function CoefficientEditFieldValueChanged(app, event)
            value = app.CoefficientEditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SelectShotLabel = uilabel(app.UIFigure);
            app.SelectShotLabel.Text = 'Select Shot: ';
            app.SelectShotDropDown = uidropdown(app.UIFigure);
            app.SelectShotDropDown.Items = {'Shot 1', 'Shot 2', 'Shot 3', 'Shot 4', 'Shot 5', 'Shot 6', 'Shot 7'};
            app.SelectShotDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectShotDropDownValueChanged, true);
            app.SelectShotDropDown.Placeholder = 'Shot 1';
            app.SelectShotDropDown.Value = 'Shot 1';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.SinglesorDoublesButtonGroup = uibuttongroup(app.UIFigure);
            app.SinglesorDoublesButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SinglesorDoublesButtonGroupSelectionChanged, true);
            app.SinglesorDoublesButtonGroup.Title = 'Singles or Doubles?';
            app.DoublesButton = uiradiobutton(app.SinglesorDoublesButtonGroup);
            app.DoublesButton.Text = 'Doubles';
            app.SinglesButton = uiradiobutton(app.SinglesorDoublesButtonGroup);
            app.SinglesButton.Text = 'Singles';
            app.SinglesButton.Value = true;
            app.VolleyorServeButtonGroup = uibuttongroup(app.UIFigure);
            app.VolleyorServeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @VolleyorServeButtonGroupSelectionChanged, true);
            app.VolleyorServeButtonGroup.Title = 'Volley or Serve?';
            app.VolleyButton = uiradiobutton(app.VolleyorServeButtonGroup);
            app.VolleyButton.Text = 'Volley';
            app.ServeButton = uiradiobutton(app.VolleyorServeButtonGroup);
            app.ServeButton.Text = 'Serve';
            app.ServeButton.Value = true;
            app.ShowCoefficientofRestitutionCheckBox = uicheckbox(app.UIFigure);
            app.ShowCoefficientofRestitutionCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowCoefficientofRestitutionCheckBoxValueChanged, true);
            app.ShowCoefficientofRestitutionCheckBox.Text = 'Show Coefficient of Restitution?';
            app.InputParametersLabel = uilabel(app.UIFigure);
            app.InputParametersLabel.Text = 'Input Parameters';
            app.OuputParametersLabel = uilabel(app.UIFigure);
            app.OuputParametersLabel.Text = 'Ouput Parameters';
            app.InorOutLabel = uilabel(app.UIFigure);
            app.InorOutLabel.Text = 'In or Out?    ';
            app.InorOutEditField = uieditfield(app.UIFigure, 'text');
            app.InorOutEditField.ValueChangedFcn = createCallbackFcn(app, @InorOutEditFieldValueChanged, true);
            app.InorOutEditField.Value = 'In';
            app.ReplayButton = uibutton(app.UIFigure, 'push');
            app.ReplayButton.ButtonPushedFcn = createCallbackFcn(app, @ReplayButtonPushed, true);
            app.ReplayButton.Text = 'Replay';
            app.FinalPositionCameraAngleEditFieldLabel = uilabel(app.UIFigure);
            app.FinalPositionCameraAngleEditFieldLabel.Text = 'Final Position Camera Angle';
            app.FinalPositionCameraAngleEditField = uieditfield(app.UIFigure, 'text');
            app.CoefficientLabel = uilabel(app.UIFigure);
            app.CoefficientLabel.Text = 'Coefficient ';
            app.CoefficientEditField = uieditfield(app.UIFigure, 'text');
            app.CoefficientEditField.ValueChangedFcn = createCallbackFcn(app, @CoefficientEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = cdr_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end